classdef fuel_injector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RPMEditField                    matlab.ui.control.NumericEditField
        RPMEditFieldLabel               matlab.ui.control.Label
        ToggleSwitch                    matlab.ui.control.ToggleSwitch
        ToggleSwitchLabel               matlab.ui.control.Label
        RPMGauge                        matlab.ui.control.Gauge
        RPMGaugeLabel                   matlab.ui.control.Label
        InjectionpulsewidthmsEditField  matlab.ui.control.NumericEditField
        InjectionpulsewidthmsEditFieldLabel  matlab.ui.control.Label
        OffsetdegreeEditField           matlab.ui.control.NumericEditField
        OffsetdegreeEditFieldLabel      matlab.ui.control.Label
        StateLamp                       matlab.ui.control.Lamp
        StateLampLabel                  matlab.ui.control.Label
        StateSwitch                     matlab.ui.control.Switch
        StateSwitchLabel                matlab.ui.control.Label
        InjectiontimingdegreeEditField  matlab.ui.control.NumericEditField
        InjectiontimingdegreeEditFieldLabel  matlab.ui.control.Label
    end

    
    methods (Access = public)
        function results = RPM_finder(~,s)
            data = fscanf(s);
            results = str2double(data);
        end
        
        function results = deg2times(~,a,RPM)
            results = a/(6*RPM);
        end
        
        function injection(~,ard,ipw,t,it)
            pause(it);
            writeDigitalPin(ard, 'D13', 1);
            pause(ipw);
            writeDigitalPin(ard, 'D13', 0);
            pause(t-ipw-it);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StateSwitch
        function StateSwitchValueChanged(app, event)
            swvalue = app.StateSwitch.Value;
            if swvalue == "On"
                app.StateLamp.Enable = "on";
                ToggleSwitchValueChanged(app,event);
            end
            if swvalue == "Off"
                app.StateLamp.Enable = "off";
                app.ToggleSwitch.Value = "Manual";
                app.RPMGauge.Value = 0;
            end
        end

        % Value changed function: ToggleSwitch
        function ToggleSwitchValueChanged(app, event)
            app.RPMEditField.Value = 0;
            app.RPMGauge.Value = 0;
            ard = arduino('COM7','Uno');
            delete(instrfind({'Port'},{'COM8'}));
            s = serial("COM8",'BaudRate',9600);
            fopen(s);
            while(1)
                it = app.InjectiontimingdegreeEditField.Value;
                ipw = app.InjectionpulsewidthmsEditField.Value;
                iof = app.OffsetdegreeEditField.Value;
                if iof<0
                    iof = 360-iof;
                end
                value = app.ToggleSwitch.Value;
                if value == "Manual"
                    RPM = app.RPMEditField.Value;
                    app.RPMGauge.Value = RPM;
                    if RPM~= 0
                        t = 60/RPM;
                        it = deg2times(app,it,RPM);
                        iof = deg2times(app,iof,RPM);
                        pause(iof);
                        injection(app,ard,ipw/1000,t,it);
                    end
                end
                if value == "Auto"
                    RPM = RPM_finder(app,s);
                    app.RPMEditField.Value = RPM;
                    app.RPMGauge.Value = RPM;
                    if RPM ~= 0
                        t = 60/RPM;
                        it = deg2times(app,it,RPM);
                        iof = deg2times(app,iof,RPM);
                        pause(iof);
                        injection(app,ard,ipw/1000,t,it);
                    end
                end
                pause(1/10000000);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InjectiontimingdegreeEditFieldLabel = uilabel(app.UIFigure);
            app.InjectiontimingdegreeEditFieldLabel.Text = 'Injection timing (degree)';
            app.InjectiontimingdegreeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StateSwitchLabel = uilabel(app.UIFigure);
            app.StateSwitchLabel.Text = 'State';
            app.StateSwitch = uiswitch(app.UIFigure, 'slider');
            app.StateSwitch.ValueChangedFcn = createCallbackFcn(app, @StateSwitchValueChanged, true);
            app.StateLampLabel = uilabel(app.UIFigure);
            app.StateLampLabel.Text = 'State';
            app.StateLamp = uilamp(app.UIFigure);
            app.StateLamp.Enable = 'off';
            app.OffsetdegreeEditFieldLabel = uilabel(app.UIFigure);
            app.OffsetdegreeEditFieldLabel.Text = 'Offset (degree)';
            app.OffsetdegreeEditField = uieditfield(app.UIFigure, 'numeric');
            app.InjectionpulsewidthmsEditFieldLabel = uilabel(app.UIFigure);
            app.InjectionpulsewidthmsEditFieldLabel.Text = 'Injection pulsewidth (ms)';
            app.InjectionpulsewidthmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.InjectionpulsewidthmsEditField.Limits = [0 30];
            app.RPMGaugeLabel = uilabel(app.UIFigure);
            app.RPMGaugeLabel.Text = 'RPM';
            app.RPMGauge = uigauge(app.UIFigure, 'circular');
            app.RPMGauge.Limits = [0 3500];
            app.ToggleSwitchLabel = uilabel(app.UIFigure);
            app.ToggleSwitchLabel.Text = 'Toggle';
            app.ToggleSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ToggleSwitch.Items = {'Auto', 'Manual'};
            app.ToggleSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleSwitchValueChanged, true);
            app.ToggleSwitch.Value = 'Manual';
            app.RPMEditFieldLabel = uilabel(app.UIFigure);
            app.RPMEditFieldLabel.Text = 'RPM';
            app.RPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.RPMEditField.Limits = [0 3500];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fuel_injector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end